classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        CarregarRNMenu               matlab.ui.container.Menu
        GuardarRNMenu                matlab.ui.container.Menu
        CriaodaredeneuronalPanel     matlab.ui.container.Panel
        ConfiguraodaredePanel        matlab.ui.container.Panel
        FunodetreinoDropDownLabel    matlab.ui.control.Label
        FunodetreinoDropDown         matlab.ui.control.DropDown
        TopologiaEditFieldLabel      matlab.ui.control.Label
        TopologiaEditField           matlab.ui.control.EditField
        FunesdeativaoEditFieldLabel  matlab.ui.control.Label
        FunesdeativaoEditField       matlab.ui.control.EditField
        FunodedivioDropDownLabel     matlab.ui.control.Label
        FunodedivioDropDown          matlab.ui.control.DropDown
        TrainEditFieldLabel          matlab.ui.control.Label
        TrainEditField               matlab.ui.control.EditField
        ValEditFieldLabel            matlab.ui.control.Label
        ValEditField                 matlab.ui.control.EditField
        TestEditFieldLabel           matlab.ui.control.Label
        TestEditField                matlab.ui.control.EditField
        InputsPanel                  matlab.ui.container.Panel
        CaminhoEditFieldLabel        matlab.ui.control.Label
        CaminhoEditField             matlab.ui.control.EditField
        EscolhercaminhoButton        matlab.ui.control.Button
        CriareTreinarRedeButton      matlab.ui.control.Button
        Label                        matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CriareTreinarRedeButton
        function CriareTreinarRedeButtonPushed(app, event)
            %Configuracao de rede
            fTreino = app.FunodetreinoDropDown.Value;
            topologia = app.TopologiaEditField.Value;
            fAtivacao = app.FunesdeativaoEditField.Value;
            fDivicao = app.FunodedivioDropDown.Value;
            trainStr = app.TrainEditField.Value;
            valStr = app.ValEditField.Value;
            testStr= app.TestEditField.Value;
            lb = app.Label;
            
            if (isempty(topologia))
                errordlg('O campo "Topologia" não pode estar vazio!','ERRO');
                return;
            end
    
            if (isempty(fAtivacao))
                errordlg('O campo "Funções de Ativação" não pode estar vazio!','ERRO');
                return;
            end
            
            if (isempty(trainStr) || isempty(testStr) || isempty(valStr))
                errordlg('Os campos train, val e test não podem estar vazios!','ERRO');
                return;
            end
            train = str2double(trainStr);
            val= str2double(valStr);
            test = str2double(testStr);
            
            if (isnumeric(train) || isnumeric(val) || isnumeric(test))
                errordlg('Os parâmetros train, val e test têm de ser números!','ERRO');
                return;
            end
            
            
            lb.Text=strcat("Função de trenio: ",fTreino," Topoloia: ",topologia);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CarregarRNMenu = uimenu(app.UIFigure);
            app.CarregarRNMenu.Text = 'Carregar RN';
            app.GuardarRNMenu = uimenu(app.UIFigure);
            app.GuardarRNMenu.Text = 'Guardar RN';
            app.CriaodaredeneuronalPanel = uipanel(app.UIFigure);
            app.CriaodaredeneuronalPanel.Title = 'Criação da rede neuronal';
            app.ConfiguraodaredePanel = uipanel(app.CriaodaredeneuronalPanel);
            app.ConfiguraodaredePanel.Title = 'Configuração da rede';
            app.FunodetreinoDropDownLabel = uilabel(app.ConfiguraodaredePanel);
            app.FunodetreinoDropDownLabel.Text = 'Função de treino';
            app.FunodetreinoDropDown = uidropdown(app.ConfiguraodaredePanel);
            app.FunodetreinoDropDown.Items = {'trainlm', 'trainrp', 'trainscg', 'traincgb', 'traincgf', 'traincgp', 'trainoss', 'traingdx'};
            app.FunodetreinoDropDown.Value = 'trainlm';
            app.TopologiaEditFieldLabel = uilabel(app.ConfiguraodaredePanel);
            app.TopologiaEditFieldLabel.Text = 'Topologia';
            app.TopologiaEditField = uieditfield(app.ConfiguraodaredePanel, 'text');
            app.FunesdeativaoEditFieldLabel = uilabel(app.ConfiguraodaredePanel);
            app.FunesdeativaoEditFieldLabel.Text = 'Funções de ativação';
            app.FunesdeativaoEditField = uieditfield(app.ConfiguraodaredePanel, 'text');
            app.FunodedivioDropDownLabel = uilabel(app.ConfiguraodaredePanel);
            app.FunodedivioDropDownLabel.Text = 'Função de divição';
            app.FunodedivioDropDown = uidropdown(app.ConfiguraodaredePanel);
            app.FunodedivioDropDown.Items = {'dividerand', 'divideblock '};
            app.FunodedivioDropDown.Value = 'dividerand';
            app.TrainEditFieldLabel = uilabel(app.ConfiguraodaredePanel);
            app.TrainEditFieldLabel.Text = 'Train';
            app.TrainEditField = uieditfield(app.ConfiguraodaredePanel, 'text');
            app.ValEditFieldLabel = uilabel(app.ConfiguraodaredePanel);
            app.ValEditFieldLabel.Text = 'Val';
            app.ValEditField = uieditfield(app.ConfiguraodaredePanel, 'text');
            app.TestEditFieldLabel = uilabel(app.ConfiguraodaredePanel);
            app.TestEditFieldLabel.Text = 'Test';
            app.TestEditField = uieditfield(app.ConfiguraodaredePanel, 'text');
            app.InputsPanel = uipanel(app.CriaodaredeneuronalPanel);
            app.InputsPanel.Title = 'Inputs';
            app.CaminhoEditFieldLabel = uilabel(app.InputsPanel);
            app.CaminhoEditFieldLabel.Text = 'Caminho';
            app.CaminhoEditField = uieditfield(app.InputsPanel, 'text');
            app.EscolhercaminhoButton = uibutton(app.InputsPanel, 'push');
            app.EscolhercaminhoButton.Text = 'Escolher caminho';
            app.CriareTreinarRedeButton = uibutton(app.CriaodaredeneuronalPanel, 'push');
            app.CriareTreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriareTreinarRedeButtonPushed, true);
            app.CriareTreinarRedeButton.Text = 'Criar e Treinar Rede';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end